classdef onelineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OneLinev12YagizArdaCicek2021UIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        H0Slider                  matlab.ui.control.Slider
        H0mSliderLabel            matlab.ui.control.Label
        TSlider                   matlab.ui.control.Slider
        TsecSliderLabel           matlab.ui.control.Label
        alpha0Slider              matlab.ui.control.Slider
        degSliderLabel            matlab.ui.control.Label
        tdaysLabel                matlab.ui.control.Label
        durationSlider            matlab.ui.control.Slider
        dtdaysLabel               matlab.ui.control.Label
        dtSlider                  matlab.ui.control.Slider
        mLabel                    matlab.ui.control.Label
        slopeSlider               matlab.ui.control.Slider
        d50mmSliderLabel          matlab.ui.control.Label
        d50Slider                 matlab.ui.control.Slider
        MaxRsEditFieldLabel       matlab.ui.control.Label
        MaxRsEditField            matlab.ui.control.NumericEditField
        H0EditField               matlab.ui.control.NumericEditField
        TEditField                matlab.ui.control.NumericEditField
        alpha0EditField           matlab.ui.control.NumericEditField
        slopeEditField            matlab.ui.control.NumericEditField
        d50EditField              matlab.ui.control.NumericEditField
        durationEditField         matlab.ui.control.NumericEditField
        dtEditField               matlab.ui.control.NumericEditField
        StartButton               matlab.ui.control.Button
        message                   matlab.ui.control.Label
        FormulationDropDownLabel  matlab.ui.control.Label
        FormulationDropDown       matlab.ui.control.DropDown
        Qm3yrLabel                matlab.ui.control.Label
        QyrEditField              matlab.ui.control.NumericEditField
        Plot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Inputs
        H0
        T
        alpha0
        slope
        d50
        duration
        dt
        formulation
        Q_init_year
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: H0Slider
        function H0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.H0EditField.Value = changingValue;
            app.H0 = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: TSlider
        function TSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TEditField.Value = changingValue;
            app.T = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: alpha0Slider
        function alpha0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.alpha0EditField.Value = changingValue;
            app.alpha0 = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: slopeSlider
        function slopeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.slopeEditField.Value = changingValue;
            app.slope = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: d50Slider
        function d50SliderValueChanging(app, event)
            changingValue = event.Value;
            app.d50EditField.Value = changingValue;
            app.d50 = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: durationSlider
        function durationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.durationEditField.Value = changingValue;
            app.duration = changingValue;

            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Value changing function: dtSlider
        function dtSliderValueChanging(app, event)
            changingValue = event.Value;
            app.dtEditField.Value = changingValue;
            app.dt = changingValue;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.H0 = app.H0EditField.Value;
            app.T = app.TEditField.Value;
            app.alpha0 = app.alpha0EditField.Value;
            app.slope = app.slopeEditField.Value;
            app.d50 = app.d50EditField.Value;
            app.duration = app.durationEditField.Value;
            app.dt = app.dtEditField.Value;
            app.formulation = app.FormulationDropDown.Value;
            app.message.Visible = "off";
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            legend(app.Plot,'Initial Profile');

        end

        % Value changed function: FormulationDropDown
        function FormulationDropDownValueChanged(app, event)
            app.formulation = app.FormulationDropDown.Value;
            
            [Ymid,xmid,maxRs,app.Q_init_year]=OneLine(app.H0,app.T,app.alpha0,app.slope,app.d50,app.duration,app.dt,245,255,app.formulation);
            app.QyrEditField.Value = app.Q_init_year;
            app.MaxRsEditField.Value = maxRs;
            sizeYmid = size(Ymid);
            rowYmid = sizeYmid(1,1);
            finalYmid = Ymid(rowYmid,:);
            finalXmid = xmid(rowYmid,:);
            [maxYmid, maxIndex] = max(finalYmid);
            [minYmid, ~] = min(finalYmid);
            
            if maxRs <= 0.5
                app.MaxRsEditField.FontColor = [0 1 0];
            else
                app.MaxRsEditField.FontColor = [1 0 0];
            end
            
            r = 100;
            x = 350;
            y = 15;
            
            % Plotting
            plot(app.Plot,xmid(size(xmid,1),:),Ymid(size(Ymid,1),:), "LineWidth",1,"Color","blue");
            ylim(app.Plot,[min(minYmid,-20) max(maxYmid,20)])
            hold(app.Plot, "on")
            rectangle(app.Plot,'Position',[730.460921843687+1 0 33.0661-3 maxYmid+3],'FaceColor',[0.50,0.50,0.50])
            plot(app.Plot,finalXmid(1,maxIndex),maxYmid,'o', "LineWidth",1.5)
            line(app.Plot,[0 1500],[0 0],'LineWidth',1,"Color", "black")                            %Initial shape, 0 = xStart, 1500 = xEnd
            if app.alpha0 > 0
                rad = 0;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,900,y,50*cos(deg2rad(200)),10*sin(deg2rad(200)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"MaxHeadSize",0,"Color","red","AutoScale","off","Marker","<")
            else
                rad = pi;
                u = r*cos(rad);
                v = r*sin(rad);
                quiver(app.Plot,x,y,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,600,y,50*cos(deg2rad(340)),10*sin(deg2rad(340)),"LineWidth",1.2,"MaxHeadSize",0.2,"Color","black","AutoScale","off")
                quiver(app.Plot,x,y-1.5,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")
                quiver(app.Plot,x,y-3,u,v,"LineWidth",1.2,"ShowArrowHead","off","Color","red","AutoScale","off","Marker",">")    
            end
            legend(app.Plot,'Final',sprintf('Maximum Accr. = %0.3f m',maxYmid),'Initial Profile',"Transport Direction","Wave Direction");
            hold(app.Plot,"off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OneLinev12YagizArdaCicek2021UIFigure = uifigure('Visible', 'off');
            app.OneLinev12YagizArdaCicek2021UIFigure.Name = 'One-Line v1.2, Yagiz Arda Cicek (2021)';
            app.OneLinev12YagizArdaCicek2021UIFigure.Icon = 'logo.PNG';
            app.GridLayout = uigridlayout(app.OneLinev12YagizArdaCicek2021UIFigure);
            app.H0Slider = uislider(app.GridLayout);
            app.H0Slider.Limits = [1 10];
            app.H0Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.H0Slider.ValueChangingFcn = createCallbackFcn(app, @H0SliderValueChanging, true);
            app.H0Slider.MinorTicks = [];
            app.H0Slider.Tooltip = {'Deep water wave height (m)'};
            app.H0Slider.Value = 4;
            app.H0mSliderLabel = uilabel(app.GridLayout);
            app.H0mSliderLabel.Tooltip = {'Deep water wave height (m)'};
            app.H0mSliderLabel.Text = 'H0 (m)';
            app.TSlider = uislider(app.GridLayout);
            app.TSlider.Limits = [1 15];
            app.TSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.TSlider.ValueChangingFcn = createCallbackFcn(app, @TSliderValueChanging, true);
            app.TSlider.MinorTicks = [];
            app.TSlider.Tooltip = {'Wave period (sec)'};
            app.TSlider.Value = 8;
            app.TsecSliderLabel = uilabel(app.GridLayout);
            app.TsecSliderLabel.Text = 'T (sec)';
            app.alpha0Slider = uislider(app.GridLayout);
            app.alpha0Slider.Limits = [-90 90];
            app.alpha0Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.alpha0Slider.MajorTickLabels = {'-90', '-60', '-30', '0', '30', '60', '90', ''};
            app.alpha0Slider.ValueChangingFcn = createCallbackFcn(app, @alpha0SliderValueChanging, true);
            app.alpha0Slider.MinorTicks = [];
            app.alpha0Slider.Tooltip = {'Deep water approach angle (deg)'; ''};
            app.degSliderLabel = uilabel(app.GridLayout);
            app.degSliderLabel.Text = 'α0 (deg)';
            app.tdaysLabel = uilabel(app.GridLayout);
            app.tdaysLabel.Text = 't (days)';
            app.durationSlider = uislider(app.GridLayout);
            app.durationSlider.Limits = [10 1000];
            app.durationSlider.MajorTicks = [10 120 230 340 450 560 670 780 890 1000];
            app.durationSlider.ValueChangingFcn = createCallbackFcn(app, @durationSliderValueChanging, true);
            app.durationSlider.MinorTicks = [];
            app.durationSlider.Tooltip = {'Duration of simulation (days)'};
            app.durationSlider.Value = 120;
            app.dtdaysLabel = uilabel(app.GridLayout);
            app.dtdaysLabel.Text = 'dt (days)';
            app.dtSlider = uislider(app.GridLayout);
            app.dtSlider.Limits = [0.1 1];
            app.dtSlider.MajorTicks = [0.1 0.4 0.7 1];
            app.dtSlider.ValueChangingFcn = createCallbackFcn(app, @dtSliderValueChanging, true);
            app.dtSlider.MinorTicks = [];
            app.dtSlider.Tooltip = {'Timestep (days)'};
            app.dtSlider.Value = 0.4;
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Text = 'm (-)';
            app.slopeSlider = uislider(app.GridLayout);
            app.slopeSlider.Limits = [0.01 0.05];
            app.slopeSlider.MajorTicks = [0.01 0.02 0.03 0.04 0.05];
            app.slopeSlider.ValueChangingFcn = createCallbackFcn(app, @slopeSliderValueChanging, true);
            app.slopeSlider.MinorTicks = [];
            app.slopeSlider.Tooltip = {'Bottom slope'};
            app.slopeSlider.Value = 0.02;
            app.d50mmSliderLabel = uilabel(app.GridLayout);
            app.d50mmSliderLabel.Text = 'd50 (mm)';
            app.d50Slider = uislider(app.GridLayout);
            app.d50Slider.Limits = [0.1 2.1];
            app.d50Slider.MajorTicks = [0.1 0.3 0.5 0.7 0.9 1.1 1.3 1.5 1.7 1.9 2.1];
            app.d50Slider.ValueChangingFcn = createCallbackFcn(app, @d50SliderValueChanging, true);
            app.d50Slider.MinorTicks = [];
            app.d50Slider.Tooltip = {'Median grain size (d50)'};
            app.d50Slider.Value = 0.3;
            app.MaxRsEditFieldLabel = uilabel(app.GridLayout);
            app.MaxRsEditFieldLabel.Text = 'Max. Rs';
            app.MaxRsEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaxRsEditField.Editable = 'off';
            app.MaxRsEditField.Tooltip = {'Maximum stability number during the simulation'};
            app.H0EditField = uieditfield(app.GridLayout, 'numeric');
            app.H0EditField.Editable = 'off';
            app.H0EditField.Value = 4;
            app.TEditField = uieditfield(app.GridLayout, 'numeric');
            app.TEditField.Editable = 'off';
            app.TEditField.Value = 8;
            app.alpha0EditField = uieditfield(app.GridLayout, 'numeric');
            app.alpha0EditField.Editable = 'off';
            app.slopeEditField = uieditfield(app.GridLayout, 'numeric');
            app.slopeEditField.Editable = 'off';
            app.slopeEditField.Value = 0.02;
            app.d50EditField = uieditfield(app.GridLayout, 'numeric');
            app.d50EditField.Editable = 'off';
            app.d50EditField.Value = 0.3;
            app.durationEditField = uieditfield(app.GridLayout, 'numeric');
            app.durationEditField.Editable = 'off';
            app.durationEditField.Value = 120;
            app.dtEditField = uieditfield(app.GridLayout, 'numeric');
            app.dtEditField.Editable = 'off';
            app.dtEditField.Value = 0.4;
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start!';
            app.message = uilabel(app.GridLayout);
            app.message.Text = {'-Press "Start" to initiate the software'; '-Please move sliders slowly.'; '   -If app does not respond close it via task manager '; '(sometimes Rs values might have '; 'imaginary component which is problematic).'};
            app.FormulationDropDownLabel = uilabel(app.GridLayout);
            app.FormulationDropDownLabel.Text = 'Formulation';
            app.FormulationDropDown = uidropdown(app.GridLayout);
            app.FormulationDropDown.Items = {'Default', 'CERC (1984)', 'Kamphuis (1991)', 'Van Rijn (2001)', 'Modified Kamphuis (2013)'};
            app.FormulationDropDown.ValueChangedFcn = createCallbackFcn(app, @FormulationDropDownValueChanged, true);
            app.FormulationDropDown.Value = 'Default';
            app.Qm3yrLabel = uilabel(app.GridLayout);
            app.Qm3yrLabel.Text = 'Q (m3/yr)';
            app.QyrEditField = uieditfield(app.GridLayout, 'numeric');
            app.QyrEditField.Editable = 'off';
            app.Plot = uiaxes(app.GridLayout);
            title(app.Plot, 'One-Line Plot')
            xlabel(app.Plot, 'Longshore Distance (m)')
            ylabel(app.Plot, 'Cross-shore Distance (m)')
            zlabel(app.Plot, 'Z')
            % Show the figure after all components are created
            app.OneLinev12YagizArdaCicek2021UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = onelineGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OneLinev12YagizArdaCicek2021UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OneLinev12YagizArdaCicek2021UIFigure)
        end
    end
end